classdef gui4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        window                 matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        GridLayout             matlab.ui.container.GridLayout
        delete_messages        matlab.ui.control.Button
        open_windows_settings  matlab.ui.control.Button
        tabs                   matlab.ui.container.TabGroup
        messages_tab           matlab.ui.container.Tab
        messages_grid          matlab.ui.container.GridLayout
        input_field            matlab.ui.control.EditField
        send_button            matlab.ui.control.Button
        msg_box                matlab.ui.control.TextArea
        settings_tab           matlab.ui.container.Tab
        settings_grid          matlab.ui.container.GridLayout
        SymbolDuration         matlab.ui.container.Panel
        duration               matlab.ui.control.Spinner
        ChannelNumberLabel_2   matlab.ui.control.Label
        ChannelPanel           matlab.ui.container.Panel
        channel                matlab.ui.control.Spinner
        ChannelNumberLabel     matlab.ui.control.Label
        PasswordPanel          matlab.ui.container.Panel
        password               matlab.ui.control.EditField
        graph                  matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: send_button
        function send_buttonButtonPushed(app, event)
            app.msg_box.Value = [app.msg_box.Value; '' ; strcat("[→] ",app.input_field.Value)];
            sender4(encrypt(char(app.input_field.Value),app.password.Value),app.channel.Value,app.duration.Value);
            app.input_field.Value = '';
        end

        % Button pushed function: delete_messages
        function delete_messagesButtonPushed(app, event)
            app.msg_box.Value = '';
        end

        % Button pushed function: open_windows_settings
        function open_windows_settingsButtonPushed(app, event)
            system('mmsys.cpl');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.window = uifigure('Visible', 'off');
            app.window.Name = 'MATLAB App';
            app.tabs = uitabgroup(app.window);
            app.messages_tab = uitab(app.tabs);
            app.messages_tab.Title = 'Messages';
            app.messages_grid = uigridlayout(app.messages_tab);
            app.msg_box = uitextarea(app.messages_grid);
            app.msg_box.Editable = 'off';
            app.send_button = uibutton(app.messages_grid, 'push');
            app.send_button.ButtonPushedFcn = createCallbackFcn(app, @send_buttonButtonPushed, true);
            app.send_button.Icon = fullfile(pathToMLAPP, 'send.svg');
            app.send_button.Text = '';
            app.input_field = uieditfield(app.messages_grid, 'text');
            app.settings_tab = uitab(app.tabs);
            app.settings_tab.Title = 'Settings';
            app.settings_grid = uigridlayout(app.settings_tab);
            app.graph = uiaxes(app.settings_grid);
            title(app.graph, 'Last Received Message')
            app.graph.XTick = [];
            app.graph.YTick = [];
            app.graph.ZTick = [];
            app.PasswordPanel = uipanel(app.settings_grid);
            app.PasswordPanel.Title = 'Password';
            app.password = uieditfield(app.PasswordPanel, 'text');
            app.password.CharacterLimits = [0 32];
            app.ChannelPanel = uipanel(app.settings_grid);
            app.ChannelNumberLabel = uilabel(app.ChannelPanel);
            app.ChannelNumberLabel.Text = 'Channel Number';
            app.channel = uispinner(app.ChannelPanel);
            app.channel.Limits = [1 2];
            app.channel.Value = 1;
            app.SymbolDuration = uipanel(app.settings_grid);
            app.ChannelNumberLabel_2 = uilabel(app.SymbolDuration);
            app.ChannelNumberLabel_2.Text = 'Symbol Duration (smp)';
            app.duration = uispinner(app.SymbolDuration);
            app.duration.Step = 20;
            app.duration.Limits = [200 24000];
            app.duration.ValueDisplayFormat = '%.0f';
            app.duration.Value = 500;
            app.Panel = uipanel(app.window);
            app.GridLayout = uigridlayout(app.Panel);
            app.open_windows_settings = uibutton(app.GridLayout, 'push');
            app.open_windows_settings.ButtonPushedFcn = createCallbackFcn(app, @open_windows_settingsButtonPushed, true);
            app.open_windows_settings.Icon = fullfile(pathToMLAPP, 'dashboard.svg');
            app.open_windows_settings.Text = '';
            app.delete_messages = uibutton(app.GridLayout, 'push');
            app.delete_messages.ButtonPushedFcn = createCallbackFcn(app, @delete_messagesButtonPushed, true);
            app.delete_messages.Icon = fullfile(pathToMLAPP, 'delete.svg');
            app.delete_messages.Text = '';
            % Show the figure after all components are created
            app.window.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.window)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.window)
        end
    end
end